classdef activity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Gauge        matlab.ui.control.SemicircularGauge
        NoviceLabel  matlab.ui.control.Label
        ProLabel     matlab.ui.control.Label
        CheckButton  matlab.ui.control.Button
        L2           matlab.ui.control.Lamp
        L1           matlab.ui.control.Lamp
        L3           matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            sig=evalin('base','XTest');
            time=0:1/256:(length(sig)-1)/256;
            profession=evalin('base','probs2');
            plot(app.UIAxes,time,sig);
            app.Gauge.Value=profession;
            if(profession>=70)
                app.L3.Color='red';
            elseif(profession<70 && profession>=50)
                app.L2.Color='yellow';
            else
                app.L1.Color='blue';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'My Reps')
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'acceleration(m/s/s)')
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.NoviceLabel = uilabel(app.UIFigure);
            app.NoviceLabel.Text = 'Novice';
            app.ProLabel = uilabel(app.UIFigure);
            app.ProLabel.Text = 'Pro';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.L2 = uilamp(app.UIFigure);
            app.L1 = uilamp(app.UIFigure);
            app.L3 = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = activity

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end